classdef Ap_compozitie_corporala < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditFieldMuschi              matlab.ui.control.NumericEditField
        EditFieldGrasime             matlab.ui.control.NumericEditField
        Panel                        matlab.ui.container.Panel
        AfisajScor                   matlab.ui.control.Label
        AfisajCategorie              matlab.ui.control.Label
        CategorieLabel               matlab.ui.control.Label
        ScorDeSanatate               matlab.ui.control.Label
        CompCorpGauge                matlab.ui.control.Label
        SCORSANATATELabel            matlab.ui.control.Label
        AfisajGaugeNr                matlab.ui.control.Label
        AfisajLabel                  matlab.ui.control.Label
        SimulatorScorSanatateLabel   matlab.ui.control.Label
        MasaMuscularaSlider          matlab.ui.control.Slider
        MasaMuscularaSliderLabel     matlab.ui.control.Label
        GrasimeCorporalaSlider       matlab.ui.control.Slider
        GrasimeCorporalaSliderLabel  matlab.ui.control.Label
        CompozitieCorporalaGauge     matlab.ui.control.SemicircularGauge
    end

    
    properties (Access = private)
        sistem_fuzzy
    end
    
    methods (Access = private)
        
        function evalueazaScorSanatate(app)
            % 1. Citim valorile curente de pe cele două slidere
            grasime = app.GrasimeCorporalaSlider.Value;
            muschi = app.MasaMuscularaSlider.Value;

            % 2. Actualizăm căsuțele numerice să arate aceeași valoare
            app.EditFieldGrasime.Value = round(grasime);
            app.EditFieldMuschi.Value = round(muschi);

            % 3. Rulăm sistemul fuzzy (Atenție la ordinea variabilelor!)
            scor = evalfis(app.sistem_fuzzy, [grasime, muschi]);
            scor_rotunjit = round(scor);

            % 4. Afișăm scorul pe ecran (pe semicercul colorat)
            app.CompozitieCorporalaGauge.Value = scor_rotunjit;
            app.AfisajGaugeNr.Text = num2str(scor_rotunjit);

            % Afișăm scorul jos în secțiunea "Scor de Sanatate" (Label-ul din stânga jos)
            app.AfisajScor.Text = [num2str(scor_rotunjit), '/100'];

            % 5. Transformăm scorul în text (Categorii)
            if scor_rotunjit < 25
                categorie = 'Critic / Urgență';
            elseif scor_rotunjit < 50
                categorie = 'Slab / Sedentar';
            elseif scor_rotunjit < 75
                categorie = 'Bun / Sănătos';
            else
                categorie = 'Optim / Atlet';
            end

            % 6. Afișăm categoria pe ecran (eticheta de deasupra semicercului și cea de jos)
            app.CompCorpGauge.Text = categorie;       % Acesta e "Label2"
            app.AfisajCategorie.Text = categorie;     % Label-ul din dreapta jos
            app.AfisajLabel.Text = ['Regula Fuzzy aplicată ne dă statusul: ', categorie]; % Banda albastră
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sistem_fuzzy = readfis('sistem_sanatate.fis');
            evalueazaScorSanatate(app);
        end

        % Value changed function: GrasimeCorporalaSlider
        function GrasimeCorporalaSliderValueChanged(app, event)
           evalueazaScorSanatate(app);
            
        end

        % Value changed function: MasaMuscularaSlider
        function MasaMuscularaSliderValueChanged(app, event)
           evalueazaScorSanatate(app);
            
        end

        % Value changed function: EditFieldGrasime
        function EditFieldGrasimeValueChanged(app, event)
         valoare = app.EditFieldGrasime.Value;
            if valoare < 5, valoare = 5; end
            if valoare > 45, valoare = 45; end
            app.GrasimeCorporalaSlider.Value = valoare;
            evalueazaScorSanatate(app);
            
        end

        % Value changed function: EditFieldMuschi
        function EditFieldMuschiValueChanged(app, event)
            valoare = app.EditFieldMuschi.Value;
            if valoare < 20, valoare = 20; end
            if valoare > 60, valoare = 60; end
            app.MasaMuscularaSlider.Value = valoare;
            evalueazaScorSanatate(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CompozitieCorporalaGauge = uigauge(app.UIFigure, 'semicircular');
            app.CompozitieCorporalaGauge.MajorTicks = [];
            app.CompozitieCorporalaGauge.MajorTickLabels = {};
            app.CompozitieCorporalaGauge.MinorTicksMode = 'manual';
            app.GrasimeCorporalaSliderLabel = uilabel(app.UIFigure);
            app.GrasimeCorporalaSliderLabel.Text = 'Grasime Corporala %';
            app.GrasimeCorporalaSlider = uislider(app.UIFigure);
            app.GrasimeCorporalaSlider.Limits = [5 45];
            app.GrasimeCorporalaSlider.MajorTicks = [5 45];
            app.GrasimeCorporalaSlider.ValueChangedFcn = createCallbackFcn(app, @GrasimeCorporalaSliderValueChanged, true);
            app.GrasimeCorporalaSlider.MinorTicksMode = 'manual';
            app.GrasimeCorporalaSlider.Value = 5;
            app.MasaMuscularaSliderLabel = uilabel(app.UIFigure);
            app.MasaMuscularaSliderLabel.Text = 'Masa Musculara %';
            app.MasaMuscularaSlider = uislider(app.UIFigure);
            app.MasaMuscularaSlider.Limits = [20 60];
            app.MasaMuscularaSlider.MajorTicks = [20 60];
            app.MasaMuscularaSlider.ValueChangedFcn = createCallbackFcn(app, @MasaMuscularaSliderValueChanged, true);
            app.MasaMuscularaSlider.MinorTicksMode = 'manual';
            app.MasaMuscularaSlider.Value = 20;
            app.SimulatorScorSanatateLabel = uilabel(app.UIFigure);
            app.SimulatorScorSanatateLabel.Text = 'Simulator Scor Sanatate';
            app.AfisajLabel = uilabel(app.UIFigure);
            app.AfisajLabel.Text = 'Text de proba';
            app.AfisajGaugeNr = uilabel(app.UIFigure);
            app.AfisajGaugeNr.Text = '88';
            app.SCORSANATATELabel = uilabel(app.UIFigure);
            app.SCORSANATATELabel.Text = 'SCOR SANATATE';
            app.CompCorpGauge = uilabel(app.UIFigure);
            app.CompCorpGauge.Text = 'Label2';
            app.Panel = uipanel(app.UIFigure);
            app.ScorDeSanatate = uilabel(app.Panel);
            app.ScorDeSanatate.Text = 'Scor de Sanatate';
            app.CategorieLabel = uilabel(app.Panel);
            app.CategorieLabel.Text = 'Categorie';
            app.AfisajCategorie = uilabel(app.Panel);
            app.AfisajScor = uilabel(app.Panel);
            app.EditFieldGrasime = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldGrasime.ValueChangedFcn = createCallbackFcn(app, @EditFieldGrasimeValueChanged, true);
            app.EditFieldMuschi = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldMuschi.ValueChangedFcn = createCallbackFcn(app, @EditFieldMuschiValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ap_compozitie_corporala

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end